classdef ViewSisterStates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GraphTab                      matlab.ui.container.Tab
        UIAxesGraph                   matlab.ui.control.UIAxes
        NodesTab                      matlab.ui.container.Tab
        UITableNodes                  matlab.ui.control.Table
        EdgesTab                      matlab.ui.container.Tab
        UITableEdges                  matlab.ui.control.Table
        SettingsTab                   matlab.ui.container.Tab
        UITableSettings               matlab.ui.control.Table
        downsampleCellsCheckBox       matlab.ui.control.CheckBox
        highTextAreaLabel_2           matlab.ui.control.Label
        numCellsSisNaiveHighTextArea  matlab.ui.control.TextArea
        lowTextAreaLabel_2            matlab.ui.control.Label
        numCellsSisNaiveLowTextArea   matlab.ui.control.TextArea
        numberofcellsingroupLabel     matlab.ui.control.Label
        lowTextAreaLabel              matlab.ui.control.Label
        numCellsSisControlledLowTextArea  matlab.ui.control.TextArea
        highTextAreaLabel             matlab.ui.control.Label
        numCellsSisControlledHighTextArea  matlab.ui.control.TextArea
        dtMeasTextArea                matlab.ui.control.TextArea
        dtMeasTextAreaLabel           matlab.ui.control.Label
        numIndMeasTextArea            matlab.ui.control.TextArea
        numIndMeasTextAreaLabel       matlab.ui.control.Label
        indMeasTextArea               matlab.ui.control.TextArea
        indMeasTextAreaLabel          matlab.ui.control.Label
        tMeasTextArea                 matlab.ui.control.TextArea
        tMeasTextAreaLabel            matlab.ui.control.Label
        tSortSlider                   matlab.ui.control.Slider
        tSortSlider_2Label            matlab.ui.control.Label
        numIndSortTextArea            matlab.ui.control.TextArea
        numIndSortTextAreaLabel       matlab.ui.control.Label
        tSortTextArea                 matlab.ui.control.TextArea
        tSortLabel                    matlab.ui.control.Label
        indSortTextArea               matlab.ui.control.TextArea
        indSortLabel                  matlab.ui.control.Label
        sortGeneDropDown              matlab.ui.control.DropDown
        sortGeneDropDownLabel         matlab.ui.control.Label
        tMeasSlider                   matlab.ui.control.Slider
        tMeasSliderLabel              matlab.ui.control.Label
        UIAxesSisNaive                matlab.ui.control.UIAxes
        UIAxesSisControl              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SortData % (S) binary state sort data measurements structure
        GraphOfNetwork % (G) graph of network
    end

    methods (Access = private)

        function results = myPlot(app)

            [tSortInd, tMeasInd, sortGene]=getKeyVariables(app);

            if app.downsampleCellsCheckBox.Value
                SisterNaiveDataHigh=app.SortData(tSortInd).(sortGene).meas(tMeasInd).data.SisterNaiveDownsampled.hi;
                SisterNaiveDataLow=app.SortData(tSortInd).(sortGene).meas(tMeasInd).data.SisterNaiveDownsampled.lo;                            
            else
                SisterNaiveDataHigh=app.SortData(tSortInd).(sortGene).meas(tMeasInd).data.SisterNaive.hi;
                SisterNaiveDataLow=app.SortData(tSortInd).(sortGene).meas(tMeasInd).data.SisterNaive.lo;                
            end

            SisterControlDataHigh=app.SortData(tSortInd).(sortGene).meas(tMeasInd).data.SisterControl.hi;
            SisterControlDataLow=app.SortData(tSortInd).(sortGene).meas(tMeasInd).data.SisterControl.lo;
            
            % PLOTS


            %% sister control
            % plot high (green)
            numGenes=height(SisterControlDataHigh);            
            x=1:numGenes;
            hold(app.UIAxesSisControl,'off');
            y=SisterControlDataHigh.phat;
            yneg=SisterControlDataHigh.phat - SisterControlDataHigh.pciLower;
            ypos=SisterControlDataHigh.pciUpper - SisterControlDataHigh.phat;
            errorbar(app.UIAxesSisControl,x,y,yneg,ypos,'go')
            %plot(app.UIAxesSisControl,x,y,'go');
            hold(app.UIAxesSisControl,'on');
            %plot(app.UIAxesSisControl,1:numGenes,SisterControlDataHigh.pciLower,'g--');
            %plot(app.UIAxesSisControl,1:numGenes,SisterControlDataHigh.pciUpper,'g--');

            % plot low (black)
            numGenes=height(SisterControlDataLow);
            x=1:numGenes;
            y=SisterControlDataLow.phat;
            yneg=SisterControlDataLow.phat - SisterControlDataLow.pciLower;
            ypos=SisterControlDataLow.pciUpper - SisterControlDataLow.phat;
            errorbar(app.UIAxesSisControl,x,y,yneg,ypos,'ko')
%            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataLow.phat,'ko');
%            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataLow.pciLower,'k--');
%            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataLow.pciUpper,'k--');
            
            % axes
            app.UIAxesSisControl.XTickLabel=SisterControlDataHigh.geneName;
            assert(isequal(SisterControlDataHigh.geneName,SisterControlDataLow.geneName))
            app.UIAxesSisControl.XLim=[0.5,numGenes+0.5];
            app.UIAxesSisControl.XTick=1:numGenes;
            app.UIAxesSisControl.YLim=[-0.1 1.1];
            app.UIAxesSisControl.YTick=[0 1];
            app.UIAxesSisControl.YTickLabel=[0 1];
            
            %% sister naive
            % plot high (green)
            numGenes=height(SisterNaiveDataHigh);
            hold(app.UIAxesSisNaive,'off');
            y=SisterNaiveDataHigh.phat;
            yneg=SisterNaiveDataHigh.phat - SisterNaiveDataHigh.pciLower;
            ypos=SisterNaiveDataHigh.pciUpper - SisterNaiveDataHigh.phat;
            %plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataHigh.phat,'go');
            errorbar(app.UIAxesSisNaive,x,y,yneg,ypos,'go')
            hold(app.UIAxesSisNaive,'on');
            %plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataHigh.pciLower,'g--');
            %plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataHigh.pciUpper,'g--');

            % plot low (black)
            numGenes=height(SisterNaiveDataLow);
            x=1:numGenes;
            y=SisterNaiveDataLow.phat;
            yneg=SisterNaiveDataLow.phat - SisterNaiveDataLow.pciLower;
            ypos=SisterNaiveDataLow.pciUpper - SisterNaiveDataLow.phat;
            errorbar(app.UIAxesSisNaive,x,y,yneg,ypos,'ko')
            %plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataLow.phat,'ko');
            %plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataLow.pciLower,'k--');
            %plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataLow.pciUpper,'k--');

            % axes
            app.UIAxesSisNaive.XTickLabel=SisterNaiveDataLow.geneName;
            assert(isequal(SisterNaiveDataHigh.geneName,SisterNaiveDataLow.geneName))
            app.UIAxesSisNaive.XLim=[0.5,numGenes+0.5];
            app.UIAxesSisNaive.XTick=1:numGenes;
            app.UIAxesSisNaive.YLim=[-0.1 1.1];
            app.UIAxesSisNaive.YTick=[0 1];
            app.UIAxesSisNaive.YTickLabel=[0 1];
            
            % get number of cells in each group
            numCellsSisterNaiveDataHigh=SisterNaiveDataHigh.nCells(1);
            numCellsSisterNaiveDataLow =SisterNaiveDataLow.nCells(1);
            numCellsSisterControlDataHigh=SisterControlDataHigh.nCells(1);
            numCellsSisterControlDataLow =SisterControlDataLow.nCells(1);
            
            % double check table row values are all same for nCells
            assert(all(SisterNaiveDataHigh.nCells(1)==SisterNaiveDataHigh.nCells))
            assert(all(SisterNaiveDataLow.nCells(1)==SisterNaiveDataLow.nCells))
            assert(all(SisterControlDataHigh.nCells(1)==SisterControlDataHigh.nCells))
            assert(all(SisterControlDataLow.nCells(1)==SisterControlDataLow.nCells))

            % update text boxes with cell numbers
            app.numCellsSisControlledHighTextArea.Value=num2str(numCellsSisterControlDataHigh);
            app.numCellsSisControlledLowTextArea.Value=num2str(numCellsSisterControlDataLow);
            
            app.numCellsSisNaiveHighTextArea.Value=num2str(numCellsSisterNaiveDataHigh);
            app.numCellsSisNaiveLowTextArea.Value=num2str(numCellsSisterNaiveDataLow);
            
        end

        function dtSortValues= getAlldtSortValues(app)
            sortGene=app.sortGeneDropDown.Value;
            dtSortValues=nan(1,length(app.SortData));
            for i=1:length(app.SortData)
                dtSortValues(i)=app.SortData(i).(sortGene).dtSort;
            end
        end

        function tMeasAllForGivenDtSortValue= getAlltMeasValuesForGivenDtSortValue(app,dtSortValue)
            sortGene=app.sortGeneDropDown.Value;
            %dtSort=app.tSortSlider.Value;
            indSort = dtSortToIndSort(app,dtSortValue);
            if ~isfield(app.SortData(indSort).(sortGene),'meas')
                error('bleh')
            else
                tMeasAllForGivenDtSortValue=[app.SortData(indSort).(sortGene).meas.time];
            end

        end

        function indSort = dtSortToIndSort(app,dtSort)
            dtSortValues= getAlldtSortValues(app);
            indSort=find(dtSort==dtSortValues);
            assert(length(indSort)==1)
        end


        function setSliderLimitsAndTicks(app)
            alltMeasForAllDtSortValues=[];
            dtSortValues= getAlldtSortValues(app);
            for i=1:length(dtSortValues)
                dtSortValue=dtSortValues(i);
                tMeasAllForGivenDtSortValue= getAlltMeasValuesForGivenDtSortValue(app,dtSortValue);
                alltMeasForAllDtSortValues=unique([alltMeasForAllDtSortValues,tMeasAllForGivenDtSortValue]);
            end

            timeSliderLimits=[min([dtSortValues,alltMeasForAllDtSortValues]),max([dtSortValues,alltMeasForAllDtSortValues])];
            app.tSortSlider.Limits=timeSliderLimits;
            app.tSortSlider.MajorTicks=dtSortValues;

            app.tMeasSlider.Limits=timeSliderLimits;
            app.tMeasSlider.MajorTicks=alltMeasForAllDtSortValues;
        end

        function [tSortInd, tMeasInd, sortGene] = getKeyVariables(app)
            sortGene=app.sortGeneDropDown.Value;

            tSort=app.tSortSlider.Value;
            tSortInd=find(tSort==getAlldtSortValues(app));
            assert(length(tSortInd)==1)

            tMeas=app.tMeasSlider.Value;
            tMeasAllTimes=[app.SortData(tSortInd).(sortGene).meas.time];
            tMeasInd=find(tMeas==tMeasAllTimes);
            assert(length(tMeasInd)==1)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SortData, GraphOfNetwork)
            app.SortData=SortData;
            
            %GraphOfNetwork (input 2)
            if nargin>=3
                if ~strcmp(class(GraphOfNetwork),'digraph')
                   error('second input must be G the network digraph') 
                end
                app.GraphOfNetwork=GraphOfNetwork;
            else
                app.GraphOfNetwork=[];
            end
            
            %sortGene=app.SortData(1);
            app.sortGeneDropDown.Items=fields(app.SortData)';
            sortGene=app.sortGeneDropDown.Items{1};
            app.sortGeneDropDown.Value=sortGene;
            app.tSortSlider.Value=app.SortData(1).(sortGene).dtSort;

            setSliderLimitsAndTicks(app)

            app.tMeasSlider.Value=app.SortData(1).(sortGene).meas(1).time;

            tSortSliderValueChanged(app) % for updating tSort and tMeas textboxes
            
            % plot graph and show node and edges tables
            G=app.GraphOfNetwork;
            if ~isempty(G)
                plot(app.UIAxesGraph,G,'EdgeLabel',G.Edges.relationship_label_short)
                app.UITableEdges.Data=G.Edges;
                app.UITableEdges.ColumnName=G.Edges.Properties.VariableNames;
                
                app.UITableNodes.Data=G.Nodes;
                app.UITableNodes.ColumnName=G.Nodes.Properties.VariableNames;
            end

        end

        % Value changed function: tMeasSlider
        function tMeasSliderValueChanged(app, event)

            % bring value to nearest MajorTick mark
            [~,MajorTickInd]=min(abs(app.tMeasSlider.MajorTicks - app.tMeasSlider.Value));
            app.tMeasSlider.Value=app.tMeasSlider.MajorTicks(MajorTickInd);

            % get key variables (tSortInd, tMeasInd, sortGene)
            [tSortInd, tMeasInd, sortGene]=getKeyVariables(app);

            % update tMeas text areas
            app.tMeasTextArea.Value=num2str(app.tMeasSlider.Value);
            app.indMeasTextArea.Value=num2str(tMeasInd);
            app.numIndMeasTextArea.Value=num2str(length(app.SortData(tSortInd).(sortGene).meas));

            app.dtMeasTextArea.Value= num2str(app.tMeasSlider.Value - app.tSortSlider.Value);
            
            myPlot(app)
        end

        % Callback function: UIAxesSisControl, sortGeneDropDown
        function DropDownValueChanged(app, event)
            %value = app.sortGeneDropDown.Value;

            % set time sliders
            setSliderLimitsAndTicks(app)
            
            % update sliders
            tSortSliderValueChanged(app)
            
            %myPlot(app)
        end

        % Drop down opening function: sortGeneDropDown
        function sortGeneDropDownOpening(app, event)
            %app.sortGeneDropDown.Items={'g1','g2'};

        end

        % Value changed function: tSortSlider
        function tSortSliderValueChanged(app, event)
            [~,MajorTickInd]=min(abs(app.tSortSlider.MajorTicks - app.tSortSlider.Value));
            app.tSortSlider.Value=app.tSortSlider.MajorTicks(MajorTickInd);

            dtSort=app.tSortSlider.Value;
            indSort = dtSortToIndSort(app,dtSort);

            % update text areas for tSort
            app.tSortTextArea.Value=num2str(dtSort);
            app.indSortTextArea.Value=num2str(indSort);
            app.numIndSortTextArea.Value=num2str(length(app.SortData));

            % update tMeas slider (may not need to be changed though)
            sortGene=app.sortGeneDropDown.Value;
            tMeasAllowable=[app.SortData(indSort).(sortGene).meas.time];
            app.tMeasSlider.MajorTicks=tMeasAllowable;
            [~,tMeasInd]=min(abs(tMeasAllowable-app.tMeasSlider.Value));
            app.tMeasSlider.Value=tMeasAllowable(tMeasInd);
            tMeasSliderValueChanged(app) % just call to update text boxes
        end

        % Value changed function: downsampleCellsCheckBox
        function downsampleCellsCheckBoxValueChanged(app, event)

            myPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSisControl = uiaxes(app.UIFigure);
            title(app.UIAxesSisControl, 'Sister-Controlled Grouped Comparison')
            ylabel(app.UIAxesSisControl, 'measured expression')
            zlabel(app.UIAxesSisControl, 'Z')
            app.UIAxesSisControl.YTick = [-1 0 1];
            app.UIAxesSisControl.YTickLabel = '';
            app.UIAxesSisControl.ButtonDownFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.UIAxesSisNaive = uiaxes(app.UIFigure);
            title(app.UIAxesSisNaive, 'Sister-Naive Grouped Comparison')
            ylabel(app.UIAxesSisNaive, 'measured expression')
            zlabel(app.UIAxesSisNaive, 'Z')
            app.UIAxesSisNaive.YTick = [-1 0 1];
            app.UIAxesSisNaive.YTickLabel = {'-1'; '0'; '1'};
            app.tMeasSliderLabel = uilabel(app.UIFigure);
            app.tMeasSliderLabel.Text = 'tMeas';
            app.tMeasSlider = uislider(app.UIFigure);
            app.tMeasSlider.ValueChangedFcn = createCallbackFcn(app, @tMeasSliderValueChanged, true);
            app.tMeasSlider.MinorTicks = [];
            app.sortGeneDropDownLabel = uilabel(app.UIFigure);
            app.sortGeneDropDownLabel.Text = 'sortGene';
            app.sortGeneDropDown = uidropdown(app.UIFigure);
            app.sortGeneDropDown.DropDownOpeningFcn = createCallbackFcn(app, @sortGeneDropDownOpening, true);
            app.sortGeneDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.indSortLabel = uilabel(app.UIFigure);
            app.indSortLabel.Text = 'indSort';
            app.indSortTextArea = uitextarea(app.UIFigure);
            app.tSortLabel = uilabel(app.UIFigure);
            app.tSortLabel.Text = 'tSort';
            app.tSortTextArea = uitextarea(app.UIFigure);
            app.tSortTextArea.Value = {'gha'};
            app.numIndSortTextAreaLabel = uilabel(app.UIFigure);
            app.numIndSortTextAreaLabel.Text = 'numIndSort';
            app.numIndSortTextArea = uitextarea(app.UIFigure);
            app.tSortSlider_2Label = uilabel(app.UIFigure);
            app.tSortSlider_2Label.Text = 'tSort';
            app.tSortSlider = uislider(app.UIFigure);
            app.tSortSlider.ValueChangedFcn = createCallbackFcn(app, @tSortSliderValueChanged, true);
            app.tSortSlider.MinorTicks = [];
            app.tMeasTextAreaLabel = uilabel(app.UIFigure);
            app.tMeasTextAreaLabel.Text = 'tMeas';
            app.tMeasTextArea = uitextarea(app.UIFigure);
            app.tMeasTextArea.Value = {'gha'};
            app.indMeasTextAreaLabel = uilabel(app.UIFigure);
            app.indMeasTextAreaLabel.Text = 'indMeas';
            app.indMeasTextArea = uitextarea(app.UIFigure);
            app.numIndMeasTextAreaLabel = uilabel(app.UIFigure);
            app.numIndMeasTextAreaLabel.Text = 'numIndMeas';
            app.numIndMeasTextArea = uitextarea(app.UIFigure);
            app.dtMeasTextAreaLabel = uilabel(app.UIFigure);
            app.dtMeasTextAreaLabel.Text = 'dtMeas';
            app.dtMeasTextArea = uitextarea(app.UIFigure);
            app.dtMeasTextArea.Value = {'0'};
            app.numCellsSisControlledHighTextArea = uitextarea(app.UIFigure);
            app.highTextAreaLabel = uilabel(app.UIFigure);
            app.highTextAreaLabel.Text = 'high';
            app.numCellsSisControlledLowTextArea = uitextarea(app.UIFigure);
            app.lowTextAreaLabel = uilabel(app.UIFigure);
            app.lowTextAreaLabel.Text = 'low';
            app.numberofcellsingroupLabel = uilabel(app.UIFigure);
            app.numberofcellsingroupLabel.Text = 'number of cells in group';
            app.numCellsSisNaiveLowTextArea = uitextarea(app.UIFigure);
            app.lowTextAreaLabel_2 = uilabel(app.UIFigure);
            app.lowTextAreaLabel_2.Text = 'low';
            app.numCellsSisNaiveHighTextArea = uitextarea(app.UIFigure);
            app.highTextAreaLabel_2 = uilabel(app.UIFigure);
            app.highTextAreaLabel_2.Text = 'high';
            app.downsampleCellsCheckBox = uicheckbox(app.UIFigure);
            app.downsampleCellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @downsampleCellsCheckBoxValueChanged, true);
            app.downsampleCellsCheckBox.Text = 'downsample cells to match sister-controlled N';
            app.downsampleCellsCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxesGraph = uiaxes(app.GraphTab);
            zlabel(app.UIAxesGraph, 'Z')
            app.UIAxesGraph.XTick = [];
            app.UIAxesGraph.YTick = [];
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.UITableNodes = uitable(app.NodesTab);
            app.UITableNodes.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableNodes.RowName = {};
            app.EdgesTab = uitab(app.TabGroup);
            app.EdgesTab.Title = 'Edges';
            app.UITableEdges = uitable(app.EdgesTab);
            app.UITableEdges.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableEdges.RowName = {};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.UITableSettings = uitable(app.SettingsTab);
            app.UITableSettings.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableSettings.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewSisterStates(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end