classdef tutorialApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        timeTextArea               matlab.ui.control.TextArea
        timeLabel                  matlab.ui.control.Label
        iTimepointTextArea         matlab.ui.control.TextArea
        timepointLabel             matlab.ui.control.Label
        GroupingGeneDropDown       matlab.ui.control.DropDown
        GroupingGeneDropDownLabel  matlab.ui.control.Label
        TimepointSlider            matlab.ui.control.Slider
        TimepointSliderLabel       matlab.ui.control.Label
        UIAxesSisNaive             matlab.ui.control.UIAxes
        UIAxesSisControl           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        StateDataMeas % binary state data measurement structure
    end
    
    methods (Access = private)
        
        function results = myPlot(app)
            
            timepointValue = app.TimepointSlider.Value;
            groupingGene=app.GroupingGeneDropDown.Value;
            
            SisterNaiveDataHigh=app.StateDataMeas(timepointValue).data.SisterNaive.(groupingGene).hi;
            SisterNaiveDataLow=app.StateDataMeas(timepointValue).data.SisterNaive.(groupingGene).lo;
            
            SisterControlDataHigh=app.StateDataMeas(timepointValue).data.SisterControl.(groupingGene).hi;
            SisterControlDataLow=app.StateDataMeas(timepointValue).data.SisterControl.(groupingGene).lo;

            %% sister control
            % plot high (green)
            numGenes=height(SisterControlDataHigh);
            hold(app.UIAxesSisControl,'off');
            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataHigh.phat,'go');
            hold(app.UIAxesSisControl,'on');
            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataHigh.pciLower,'g--');
            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataHigh.pciUpper,'g--');

            % plot low (black)
            numGenes=height(SisterControlDataLow);
            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataLow.phat,'ko');
            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataLow.pciLower,'k--');
            plot(app.UIAxesSisControl,1:numGenes,SisterControlDataLow.pciUpper,'k--');

            % axes
            app.UIAxesSisControl.XTickLabel=SisterControlDataHigh.geneName;
            assert(isequal(SisterControlDataHigh.geneName,SisterControlDataLow.geneName))
            app.UIAxesSisControl.XLim=[0.5,numGenes+0.5];

            %% sister naive
            % plot high (green)
            numGenes=height(SisterNaiveDataHigh);
            hold(app.UIAxesSisNaive,'off');
            plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataHigh.phat,'go');
            hold(app.UIAxesSisNaive,'on');
            plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataHigh.pciLower,'g--');
            plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataHigh.pciUpper,'g--');

            % plot low (black)
            numGenes=height(SisterNaiveDataLow);
            plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataLow.phat,'ko');
            plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataLow.pciLower,'k--');
            plot(app.UIAxesSisNaive,1:numGenes,SisterNaiveDataLow.pciUpper,'k--');

            % axes
            app.UIAxesSisNaive.XTickLabel=SisterNaiveDataLow.geneName;
            assert(isequal(SisterNaiveDataHigh.geneName,SisterNaiveDataLow.geneName))
            app.UIAxesSisNaive.XLim=[0.5,numGenes+0.5];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, StateDataMeas)
            
            % timepoints slider
            numTimepoints=length(StateDataMeas);
            app.GroupingGeneDropDown.Items=fields(StateDataMeas(1).data.SisterNaive);
            app.TimepointSlider.Limits=[1,numTimepoints];
            %app.TimepointSlider.MajorTickLabels=replace(cellstr(num2str([1:numTimepoints]'))',' ','');
            
            % times
            times=[StateDataMeas.time];
            
            app.StateDataMeas=StateDataMeas;

            myPlot(app)

        end

        % Value changed function: TimepointSlider
        function TimepointSliderValueChanged(app, event)
            app.TimepointSlider.Value=round(app.TimepointSlider.Value);
            
            iTimepoint=app.TimepointSlider.Value;
            time=app.StateDataMeas(iTimepoint).time;
            app.iTimepointTextArea.Value=num2str(app.TimepointSlider.Value);
            app.timeTextArea.Value=num2str(time);

            myPlot(app)
            %
        end

        % Callback function: GroupingGeneDropDown, UIAxesSisControl
        function DropDownValueChanged(app, event)
            %value = app.GroupingGeneDropDown.Value;
            myPlot(app)
        end

        % Drop down opening function: GroupingGeneDropDown
        function GroupingGeneDropDownOpening(app, event)
            %app.GroupingGeneDropDown.Items={'g1','g2'};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSisControl = uiaxes(app.UIFigure);
            title(app.UIAxesSisControl, 'Sister-Controlled Grouped Comparison')
            xlabel(app.UIAxesSisControl, 'X')
            ylabel(app.UIAxesSisControl, 'Y')
            zlabel(app.UIAxesSisControl, 'Z')
            app.UIAxesSisControl.YTick = [-1 0 1];
            app.UIAxesSisControl.YTickLabel = '';
            app.UIAxesSisControl.ButtonDownFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.UIAxesSisNaive = uiaxes(app.UIFigure);
            title(app.UIAxesSisNaive, 'Sister-Naive Grouped Comparison')
            xlabel(app.UIAxesSisNaive, 'X')
            ylabel(app.UIAxesSisNaive, 'Y')
            zlabel(app.UIAxesSisNaive, 'Z')
            app.UIAxesSisNaive.YTick = [-1 0 1];
            app.UIAxesSisNaive.YTickLabel = {'-1'; '0'; '1'};
            app.TimepointSliderLabel = uilabel(app.UIFigure);
            app.TimepointSliderLabel.Text = 'Timepoint';
            app.TimepointSlider = uislider(app.UIFigure);
            app.TimepointSlider.ValueChangedFcn = createCallbackFcn(app, @TimepointSliderValueChanged, true);
            app.GroupingGeneDropDownLabel = uilabel(app.UIFigure);
            app.GroupingGeneDropDownLabel.Text = 'GroupingGene';
            app.GroupingGeneDropDown = uidropdown(app.UIFigure);
            app.GroupingGeneDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GroupingGeneDropDownOpening, true);
            app.GroupingGeneDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.timepointLabel = uilabel(app.UIFigure);
            app.timepointLabel.Text = 'timepoint';
            app.iTimepointTextArea = uitextarea(app.UIFigure);
            app.timeLabel = uilabel(app.UIFigure);
            app.timeLabel.Text = 'time';
            app.timeTextArea = uitextarea(app.UIFigure);
            app.timeTextArea.Value = {'gha'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end